classdef AcquireDataGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxesVideo                 matlab.ui.control.UIAxes
        UIAxesSnapshot              matlab.ui.control.UIAxes
        StreamCaptureDataDemoLabel  matlab.ui.control.Label
        BeginButton                 matlab.ui.control.Button
        CaptureButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        cam = true;                                                             % Initialize webcam for data acquisition
        imvideo = 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            cam = webcam(2);
            frame = snapshot(cam);
            im = image(app.UIAxesVideo,zeros(size(frame)));
            axis(app.UIAxesVideo,'image');
            
            preview(cam,im);
            pause;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesVideo = uiaxes(app.UIFigure);
            title(app.UIAxesVideo, 'Title')
            xlabel(app.UIAxesVideo, 'X')
            ylabel(app.UIAxesVideo, 'Y')
            app.UIAxesSnapshot = uiaxes(app.UIFigure);
            title(app.UIAxesSnapshot, 'Title')
            xlabel(app.UIAxesSnapshot, 'X')
            ylabel(app.UIAxesSnapshot, 'Y')
            app.StreamCaptureDataDemoLabel = uilabel(app.UIFigure);
            app.StreamCaptureDataDemoLabel.Text = 'Stream & Capture Data Demo';
            app.BeginButton = uibutton(app.UIFigure, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.CaptureButton = uibutton(app.UIFigure, 'push');
            app.CaptureButton.Text = 'Capture';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AcquireDataGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end